classdef App_Example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Label1_2                        matlab.ui.control.Label
        HoughSpinner                    matlab.ui.control.Spinner
        HoughSpinnerLabel               matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SensitivitySpinner              matlab.ui.control.Spinner
        SensitivitySpinnerLabel         matlab.ui.control.Label
        CannySpinner                    matlab.ui.control.Spinner
        CannySpinnerLabel               matlab.ui.control.Label
        PROYECTO_DeteccinvehculosLabel  matlab.ui.control.Label
        apply                           matlab.ui.control.Button
        open_file                       matlab.ui.control.Button
        Label1                          matlab.ui.control.Label
        imag_orig                       matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        new_window                      matlab.ui.control.Button
        saveas                          matlab.ui.control.Button
        copy_orig                       matlab.ui.control.Button
        Label2                          matlab.ui.control.Label
        imag_processed_final            matlab.ui.control.UIAxes
        imag_processed_separar          matlab.ui.control.UIAxes
        imag_processed_umbralizacion    matlab.ui.control.UIAxes
        imag_processed_canny            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imag_orig_mat % pixel values of the original image
        imag_proc_mat % pixel values of the processed image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            axis(app.imag_orig, 'image');
            axis(app.imag_processed_canny, 'image');            
        end

        % Button pushed function: open_file
        function open_fileButtonPushed(app, event)
            
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
               errordlg('The image must be colored');
               return
            end
            
            imshow(image,'Parent',app.imag_orig,'InitialMagnification','fit');
            %imagesc(app.imag_orig,image);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: saveas
        function saveasButtonPushed(app, event)

            [filename, pathname]= uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Save image file as ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=app.imag_processed_final;
            
            imwrite(image,strcat(pathname,filename));            
        end

        % Button pushed function: new_window
        function new_windowPushed(app, event)

            image=app.imag_proc_mat;
            
            h=figure;
            imshow(image,'Parent',axes(h));            
        end

        % Button pushed function: copy_orig
        function copy_origButtonPushed(app, event)

            image=app.imag_processed_final;
            
            imshow(image,'Parent',app.imag_orig);
            
            app.imag_orig_mat=image;            
        end

        % Callback function: apply, imag_processed_umbralizacion
        function applyButtonPushed(app, event)
        %Coger la imagen requerida
        image=app.imag_orig_mat;
        imagen_final=image;
        %Pasar al gris, eliminar ruidos y detectar color acerca de negro y blanco
        imagen_gris=rgb2gray(image);
        imagen_filtrada=imnlmfilt(imagen_gris);
        imagen_umbralizacion = (imagen_filtrada < 70) | (imagen_filtrada > 230);
                
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  1 

        imshow(imagen_umbralizacion, 'Parent', app.imag_processed_umbralizacion);

        valor1=app.CannySpinner.Value; 
        
        %Detectar borde con metodo canny con 0.2 de sensitividad,que no sea tan
        %sensible a los detelles y se enfoca más al borde
        mascara_bordes = edge(imagen_umbralizacion, 'canny',valor1);
        %subplot(2,3,3);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  2
        %imshow(mascara_bordes);
        imshow(mascara_bordes, 'Parent', app.imag_processed_canny);

        valor2 = app.SensitivitySpinner.Value;

        %Detectar circulos de radio de radio entre 10 y 70
        rd=[10,70];
        [centers,radii]=imfindcircles(image,rd,'ObjectPolarity','dark','Sensitivity',valor2);
        num_rueda =0; 
        valor3 = app.HoughSpinner.Value;
        grupos = cell(size(centers, 1), 1);
        %viscircles(centers,radii);
        %Detectar las primeras 10 rectas más fuerte
        [H, theta, rho] = hough(mascara_bordes);
        peaks = houghpeaks(H, valor3);
        lines = houghlines(mascara_bordes, theta, rho, peaks);
        umbral_distancia = 200;
    % Dibujar solo las rectas que están cerca de un círculo
        for i = 1:length(lines)
            for j = 1:size(centers, 1)
                image = insertShape(image, 'FilledCircle', [centers,radii], 'Color', 'green', 'LineWidth', 8);
                distancia = norm([lines(i).point1 - centers(j,:), lines(i).point2 - centers(j,:)]);
                if distancia < umbral_distancia
                    xy = [lines(i).point1; lines(i).point2];
                    grupos{j} = [grupos{j}; i];
                    image = insertShape(image, 'Line', xy, 'Color', 'green', 'LineWidth', 8);
                    break;
                end
            end
            
            num_rueda = j;
        end

%Distinguir y separar los objectos detectados

imshow(image, 'Parent', app.imag_processed_separar);
app.TextArea.Value= "Hay un total de " + num2str(num_rueda) + " ruedas";


% Un bucle que recorre todos los elementos
% Crea una imagen en blanco del mismo tamaño que la imagen original

%Un bucle que recorre todos los elementos
for i = 1:length(centers) 
        centro_actual = grupos{i};
        
        for j = i+1:length(centers)
            if ~isempty(grupos{j})
                %Calcular la distancia entre ellos,si estan lejos, se
                %dibuja el circulo y lineas
                distancia_x = abs(centro_actual(1) - centers(j,1));
                distancia_y = abs(centro_actual(2) - centers(j,2));
                distancia_total = sqrt(distancia_x^2 + distancia_y^2);
                radio_actual = radii(i);
                radio_otro = radii(j);
                radio_total=abs(radio_otro-radio_actual);                
                fprintf('Distancia total entre el centro %d y el centro %d: %f\n', i, j, distancia_total); 
            end

            %Si haya otro circulo que esta cerca, se repite el color
            if(distancia_total<700 && radio_total<5)
                imagen_final = insertShape(imagen_final, 'FilledCircle', [centers(i,:), radii(i)], 'Color', 'red', 'LineWidth', 8);
                imagen_final = insertShape(imagen_final, 'FilledCircle', [centers(j,:), radii(j)], 'Color', 'red', 'LineWidth', 8);
                for l = 1:length(centro_actual) 
                idx = grupos{i}(l);
                xy = [lines(idx).point1; lines(idx).point2];
                imagen_final = insertShape(imagen_final, 'Line', xy, 'Color', 'red', 'LineWidth', 8);
                end
%                 for p = 1:length(centro_actual) 
%                 idx = grupos{j}(p);
%                 xy = [lines(idx).point1; lines(idx).point2];
%                 imagen_final = insertShape(imagen_final, 'Line', xy, 'Color','red', 'LineWidth', 8);
%                 end
       
            else
                imagen_final = insertShape(imagen_final, 'FilledCircle', [centers(i,:), radii(i)], 'Color', 'blue', 'LineWidth', 8);
                for l = 1:length(centro_actual) 
                idx = grupos{i}(l);
                xy = [lines(idx).point1; lines(idx).point2];
                imagen_final = insertShape(imagen_final, 'Line', xy, 'Color','blue', 'LineWidth', 8);
                end
    
            end
        end
end
imshow(imagen_final, 'Parent', app.imag_processed_final);


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {792, 792};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {631, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.imag_orig = uiaxes(app.LeftPanel);
            app.imag_orig.XTick = [];
            app.imag_orig.XTickLabel = {'[ ]'};
            app.imag_orig.YTick = [];
            app.imag_orig.Visible = 'off';
            app.Label1 = uilabel(app.LeftPanel);
            app.Label1.Text = 'ORIGINAL IMAGE';
            app.open_file = uibutton(app.LeftPanel, 'push');
            app.open_file.ButtonPushedFcn = createCallbackFcn(app, @open_fileButtonPushed, true);
            app.open_file.Text = 'Open ...';
            app.apply = uibutton(app.LeftPanel, 'push');
            app.apply.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.apply.Text = 'APPLY';
            app.PROYECTO_DeteccinvehculosLabel = uilabel(app.LeftPanel);
            app.PROYECTO_DeteccinvehculosLabel.Text = 'PROYECTO_Detección vehículos';
            app.CannySpinnerLabel = uilabel(app.LeftPanel);
            app.CannySpinnerLabel.Text = 'Canny';
            app.CannySpinner = uispinner(app.LeftPanel);
            app.CannySpinner.Step = 0.1;
            app.CannySpinner.Limits = [0 10];
            app.CannySpinner.Value = 0.2;
            app.SensitivitySpinnerLabel = uilabel(app.LeftPanel);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity';
            app.SensitivitySpinner = uispinner(app.LeftPanel);
            app.SensitivitySpinner.Step = 0.1;
            app.SensitivitySpinner.Limits = [0 10];
            app.SensitivitySpinner.Value = 0.7;
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.HoughSpinnerLabel = uilabel(app.LeftPanel);
            app.HoughSpinnerLabel.Text = 'Hough';
            app.HoughSpinner = uispinner(app.LeftPanel);
            app.HoughSpinner.Step = 0.1;
            app.HoughSpinner.Limits = [0 100];
            app.HoughSpinner.Value = 10;
            app.Label1_2 = uilabel(app.LeftPanel);
            app.Label1_2.Text = 'Chenyi Jiang & Chen Ruiyu';
            app.RightPanel = uipanel(app.GridLayout);
            app.imag_processed_canny = uiaxes(app.RightPanel);
            app.imag_processed_canny.XTick = [];
            app.imag_processed_canny.XTickLabel = {'[ ]'};
            app.imag_processed_canny.YTick = [];
            app.imag_processed_canny.Visible = 'off';
            app.imag_processed_umbralizacion = uiaxes(app.RightPanel);
            app.imag_processed_umbralizacion.XTick = [];
            app.imag_processed_umbralizacion.XTickLabel = {'[ ]'};
            app.imag_processed_umbralizacion.YTick = [];
            app.imag_processed_umbralizacion.Visible = 'off';
            app.imag_processed_umbralizacion.ButtonDownFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.imag_processed_separar = uiaxes(app.RightPanel);
            app.imag_processed_separar.XTick = [];
            app.imag_processed_separar.XTickLabel = {'[ ]'};
            app.imag_processed_separar.YTick = [];
            app.imag_processed_separar.Visible = 'off';
            app.imag_processed_final = uiaxes(app.RightPanel);
            app.imag_processed_final.XTick = [];
            app.imag_processed_final.XTickLabel = {'[ ]'};
            app.imag_processed_final.YTick = [];
            app.imag_processed_final.Visible = 'off';
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'PROCESSED IMAGE';
            app.copy_orig = uibutton(app.RightPanel, 'push');
            app.copy_orig.ButtonPushedFcn = createCallbackFcn(app, @copy_origButtonPushed, true);
            app.copy_orig.Text = 'Copy to Original';
            app.saveas = uibutton(app.RightPanel, 'push');
            app.saveas.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveas.Text = 'Save As ...';
            app.new_window = uibutton(app.RightPanel, 'push');
            app.new_window.ButtonPushedFcn = createCallbackFcn(app, @new_windowPushed, true);
            app.new_window.Text = 'New Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end